classdef param < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        WaterTempEditField              matlab.ui.control.NumericEditField
        WaterTempEditFieldLabel         matlab.ui.control.Label
        ChooseSavingFolderButton        matlab.ui.control.Button
        coliscurrentCC0EditField_2      matlab.ui.control.NumericEditField
        coliscurrentCC0EditField_2Label  matlab.ui.control.Label
        coliscurrentCC1EditField_2      matlab.ui.control.NumericEditField
        coliscurrentCC1EditField_2Label  matlab.ui.control.Label
        coliscurrentCC2EditField_2      matlab.ui.control.NumericEditField
        coliscurrentCC2EditField_2Label  matlab.ui.control.Label
        coliscurrentCC3EditField_2      matlab.ui.control.NumericEditField
        coliscurrentCC3EditField_2Label  matlab.ui.control.Label
        coliscurrentCC4EditField_2      matlab.ui.control.NumericEditField
        coliscurrentCC4EditField_2Label  matlab.ui.control.Label
        coliscurrentCC5EditField_2      matlab.ui.control.NumericEditField
        coliscurrentCC5EditField_2Label  matlab.ui.control.Label
        current_bottomEditField_2       matlab.ui.control.NumericEditField
        current_bottomEditField_2Label  matlab.ui.control.Label
        voltage_bottomEditField_2       matlab.ui.control.NumericEditField
        voltage_bottomEditField_2Label  matlab.ui.control.Label
        current_topEditField_2          matlab.ui.control.NumericEditField
        current_topEditField_2Label     matlab.ui.control.Label
        Voltage_topEditField_2          matlab.ui.control.NumericEditField
        Voltage_topEditField_2Label     matlab.ui.control.Label
        TemperatureEditField_2          matlab.ui.control.NumericEditField
        TemperatureEditField_2Label     matlab.ui.control.Label
        saveButton                      matlab.ui.control.Button
    end

    properties (Access = private)
        roomtemp % Description
        watertemp % Description
        Voltt
        currt
        Voltb
        currb
        CC5
        CC4
        CC3
        CC2
        CC1
        CC0
        selpath
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TemperatureEditField_2
        function RoomtempEditField_2ValueChanged(app, event)
            app.roomtemp = app.TemperatureEditField_2.Value;
            
        end

        % Value changed function: WaterTempEditField
        function WaterTempEditFieldValueChanged(app, event)
            app.watertemp = app.WaterTempEditField.Value;
            
        end

        % Value changed function: Voltage_topEditField_2
        function Voltage_topEditField_2ValueChanged(app, event)
            app.Voltt = app.Voltage_topEditField_2.Value;
            
        end

        % Value changed function: current_topEditField_2
        function current_topEditField_2ValueChanged(app, event)
            app.currt = app.current_topEditField_2.Value;
            
        end

        % Value changed function: voltage_bottomEditField_2
        function voltage_bottomEditField_2ValueChanged(app, event)
            app.Voltb = app.voltage_bottomEditField_2.Value;
            
        end

        % Value changed function: current_bottomEditField_2
        function current_bottomEditField_2ValueChanged(app, event)
            app.currb = app.current_bottomEditField_2.Value;
            
        end

        % Value changed function: coliscurrentCC5EditField_2
        function coliscurrentCC5EditField_2ValueChanged(app, event)
            app.CC5 = app.coliscurrentCC5EditField_2.Value;
            
        end

        % Value changed function: coliscurrentCC4EditField_2
        function coliscurrentCC4EditField_2ValueChanged(app, event)
            app.CC4 = app.coliscurrentCC4EditField_2.Value;
            
        end

        % Value changed function: coliscurrentCC3EditField_2
        function coliscurrentCC3EditField_2ValueChanged(app, event)
            app.CC3 = app.coliscurrentCC3EditField_2.Value;
            
        end

        % Value changed function: coliscurrentCC2EditField_2
        function coliscurrentCC2EditField_2ValueChanged(app, event)
            app.CC2 = app.coliscurrentCC2EditField_2.Value;
            
        end

        % Value changed function: coliscurrentCC1EditField_2
        function coliscurrentCC1EditField_2ValueChanged(app, event)
            app.CC1 = app.coliscurrentCC1EditField_2.Value;
            
        end

        % Value changed function: coliscurrentCC0EditField_2
        function coliscurrentCC0EditField_2ValueChanged(app, event)
            app.CC0 = app.coliscurrentCC0EditField_2.Value;
            
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            f = waitbar(0,'Please wait...');
            tobesaved = struct;
            tobesaved.roomtemp = app.roomtemp;
            tobesaved.watertemp = app.watertemp;
            tobesaved.Voltt = app.Voltt;
            tobesaved.currt = app.currt;
            tobesaved.Voltb = app.Voltb;
            tobesaved.currb = app.currb;
            tobesaved.CC5 = app.CC5;
            tobesaved.CC4 = app.CC4;
            tobesaved.CC3 = app.CC3;
            tobesaved.CC2 = app.CC2;
            tobesaved.CC1 = app.CC1;
            tobesaved.CC0 = app.CC0;
            savepath = app.selpath;
            savetime = char(datetime('now','Format','dd_MMM_uuuu_HH_mm_ss'));
            save([savepath '\param_' savetime '.mat'],'-struct','tobesaved','-mat')
            waitbar(1,f,'Done');
            pause(0.5)
            close(f)
        end

        % Button pushed function: ChooseSavingFolderButton
        function ChooseSavingFolderButtonPushed(app, event)
            app.selpath = uigetdir;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save ';
            app.TemperatureEditField_2Label = uilabel(app.UIFigure);
            app.TemperatureEditField_2Label.Text = 'Temperature';
            app.TemperatureEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureEditField_2.ValueChangedFcn = createCallbackFcn(app, @RoomtempEditField_2ValueChanged, true);
            app.Voltage_topEditField_2Label = uilabel(app.UIFigure);
            app.Voltage_topEditField_2Label.Text = 'Voltage_top';
            app.Voltage_topEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Voltage_topEditField_2.ValueChangedFcn = createCallbackFcn(app, @Voltage_topEditField_2ValueChanged, true);
            app.current_topEditField_2Label = uilabel(app.UIFigure);
            app.current_topEditField_2Label.Text = 'current_top';
            app.current_topEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.current_topEditField_2.ValueChangedFcn = createCallbackFcn(app, @current_topEditField_2ValueChanged, true);
            app.voltage_bottomEditField_2Label = uilabel(app.UIFigure);
            app.voltage_bottomEditField_2Label.Text = 'voltage_bottom';
            app.voltage_bottomEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.voltage_bottomEditField_2.ValueChangedFcn = createCallbackFcn(app, @voltage_bottomEditField_2ValueChanged, true);
            app.current_bottomEditField_2Label = uilabel(app.UIFigure);
            app.current_bottomEditField_2Label.Text = 'current_bottom';
            app.current_bottomEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.current_bottomEditField_2.ValueChangedFcn = createCallbackFcn(app, @current_bottomEditField_2ValueChanged, true);
            app.coliscurrentCC5EditField_2Label = uilabel(app.UIFigure);
            app.coliscurrentCC5EditField_2Label.Text = 'coli''s current CC5';
            app.coliscurrentCC5EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.coliscurrentCC5EditField_2.ValueChangedFcn = createCallbackFcn(app, @coliscurrentCC5EditField_2ValueChanged, true);
            app.coliscurrentCC4EditField_2Label = uilabel(app.UIFigure);
            app.coliscurrentCC4EditField_2Label.Text = 'coli''s current CC4';
            app.coliscurrentCC4EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.coliscurrentCC4EditField_2.ValueChangedFcn = createCallbackFcn(app, @coliscurrentCC4EditField_2ValueChanged, true);
            app.coliscurrentCC3EditField_2Label = uilabel(app.UIFigure);
            app.coliscurrentCC3EditField_2Label.Text = 'coli''s current CC3';
            app.coliscurrentCC3EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.coliscurrentCC3EditField_2.ValueChangedFcn = createCallbackFcn(app, @coliscurrentCC3EditField_2ValueChanged, true);
            app.coliscurrentCC2EditField_2Label = uilabel(app.UIFigure);
            app.coliscurrentCC2EditField_2Label.Text = 'coli''s current CC2';
            app.coliscurrentCC2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.coliscurrentCC2EditField_2.ValueChangedFcn = createCallbackFcn(app, @coliscurrentCC2EditField_2ValueChanged, true);
            app.coliscurrentCC1EditField_2Label = uilabel(app.UIFigure);
            app.coliscurrentCC1EditField_2Label.Text = 'coli''s current CC1';
            app.coliscurrentCC1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.coliscurrentCC1EditField_2.ValueChangedFcn = createCallbackFcn(app, @coliscurrentCC1EditField_2ValueChanged, true);
            app.coliscurrentCC0EditField_2Label = uilabel(app.UIFigure);
            app.coliscurrentCC0EditField_2Label.Text = 'coli''s current CC0';
            app.coliscurrentCC0EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.coliscurrentCC0EditField_2.ValueChangedFcn = createCallbackFcn(app, @coliscurrentCC0EditField_2ValueChanged, true);
            app.ChooseSavingFolderButton = uibutton(app.UIFigure, 'push');
            app.ChooseSavingFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseSavingFolderButtonPushed, true);
            app.ChooseSavingFolderButton.Text = 'Choose Saving Folder';
            app.WaterTempEditFieldLabel = uilabel(app.UIFigure);
            app.WaterTempEditFieldLabel.Text = 'Water Temp';
            app.WaterTempEditField = uieditfield(app.UIFigure, 'numeric');
            app.WaterTempEditField.ValueChangedFcn = createCallbackFcn(app, @WaterTempEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = param

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end